classdef pspm_appdesigner2019 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        layout                   matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        title_help               matlab.ui.control.Label
        title_data_processing    matlab.ui.control.Label
        title_data_display       matlab.ui.control.Label
        button_logo              matlab.ui.control.Button
        panel_data_display       matlab.ui.container.Panel
        button_data_editor       matlab.ui.control.Button
        button_ecg_editor        matlab.ui.control.Button
        button_model_review      matlab.ui.control.Button
        button_data_display      matlab.ui.control.Button
        panel_data_processing    matlab.ui.container.Panel
        button_batch             matlab.ui.control.Button
        panel_quit               matlab.ui.container.Panel
        button_quit              matlab.ui.control.Button
        panel_help               matlab.ui.container.Panel
        button_reference_manual  matlab.ui.control.Button
        button_discussion_forum  matlab.ui.control.Button
        text_attribution         matlab.ui.control.Label
    end

  methods (Access = private)
    function Import_data_Callback(~)
      % pspm_import_UI;
      cfg_add_module('pspm.prep.import');
    end
    function Trim_data_Callback(~)
      % pspm_trim_UI;
      cfg_add_module('pspm.prep.trim');
    end
    function dispdata_Callback(~)
      % pspm_disp;
      cfg_add_module('pspm.tools.disp');
    end
    % Update components that require runtime configuration
    function addRuntimeConfigurations(app)
      % Load data for component configuration
      componentData = load('pspm_appdesigner.mat');
      app = pspm_ui_app(app);
      % Set component properties that require runtime configuration
      %app.button_tools.UserData = componentData.Other_utils.UserData;
      %app.button_data_preprocessing.UserData = componentData.ppDataPreprocessing.UserData;
      %app.button_data_preparation.UserData = componentData.ppDataPreparation.UserData;
    end
  end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PsPM_OpeningFcn(app, varargin)
      addRuntimeConfigurations(app);
      pspm_init; % required for cfg_util('initcfg');
      cfg_util('initcfg'); % This must be the first call to cfg_util
        end

        % Callback function
        function button_non_linear_scr_model_Callback(app, event)
      % pspm_dcm_UI;
      cfg_add_module('pspm.first_level.scr.dcm');
        end

        % Callback function
        function button_export_statistics_Callback(app, event)
      % pspm_exp_UI;
      cfg_add_module('pspm.first_level.export');
        end

        % Callback function
        function button_first_level_models_Callback(app, event)
      switch event.Value
        case 'GLM for SCR'
          cfg_add_module('pspm.first_level.scr.glm_scr');
        case 'GLM for HPR (evoked)'
          cfg_add_module('pspm.first_level.hp.glm_hp_e');
        case 'GLM for HPR (fear-conditioning)'
          cfg_add_module('pspm.first_level.hp.glm_hp_fc');
        case 'GLM for PSR (fear-conditioning)'
          cfg_add_module('pspm.first_level.ps.glm_ps_fc');
        case 'GLM for RAR (evoked)'
          cfg_add_module('pspm.first_level.resp.glm_ra_e');
        case 'GLM for RAR (fear-conditioning)'
          cfg_add_module('pspm.first_level.resp.glm_ra_fc');
        case 'GLM for RPR (evoked)'
          cfg_add_module('pspm.first_level.resp.glm_rp_e');
        case 'GLM for RFRR (evoked)'
          cfg_add_module('pspm.first_level.resp.glm_rfr_e');
        case 'GLM for SEBR'
          cfg_add_module('pspm.first_level.sebr.glm_sebr');
        case 'GLM for SPS (fear-conditioning)'
          cfg_add_module('pspm.first_level.sps.glm_sps');
      end
        end

        % Callback function
        function button_tools_Callback(app, event)
      switch event.Value
        case 'Display data'
          pspm_display;
        case 'Rename file'
          cfg_add_module('pspm.tools.rename');
        case 'Split sessions'
          cfg_add_module('pspm.tools.split_sessions');
        case 'Merge files'
          cfg_add_module('pspm.tools.merge');
        case 'Artefact removal'
          cfg_add_module('pspm.tools.artefact_rm');
        case 'Downsample data'
          cfg_add_module('pspm.tools.downsample');
        case 'Interpolate missing data'
          cfg_add_module('pspm.tools.interpolate');
        case 'Extract segments'
          cfg_add_module('pspm.tools.extract_segments');
        case 'Segment mean'
          cfg_add_module('pspm.tools.segment_mean');
        case 'Extract event marker info'
          cfg_add_module('pspm.tools.extract_markerinfo');
        case 'Data editor'
          pspm_data_editor();
        case 'Data convert'
          cfg_add_module('pspm.tools.convert_data');
      end
        end

        % Button pushed function: button_discussion_forum
        function PsPM_ButtonDownFcnForum(app, event)
      pspm_show_forum;
        end

        % Button pushed function: button_reference_manual
        function PsPM_ButtonDownFcnHelp(app, event)
      pspm_show_help_doc;
        end

        % Button pushed function: button_quit
        function button_quit_Callback(app, event)
      pspm_quit;
      delete(app)
      return
        end

        % Callback function
        function button_models_for_sf_Callback(app, event)
      cfg_add_module('pspm.first_level.scr.sf');
        end

        % Button pushed function: button_batch
        function button_batch_Callback(app, event)
      cfg_ui;
        end

        % Callback function
        function button_contrast_manager_Callback(app, event)
      pspm_contrast;
        end

        % Callback function
        function button_second_level_model_Callback(app, event)
      cfg_add_module('pspm.second_level.contrast');
        end

        % Callback function
        function button_data_preparation_Callback(app, event)
      switch event.Value
        case 'Import'
          cfg_add_module('pspm.prep.import');
        case 'Trim'
          cfg_add_module('pspm.prep.trim');
        case 'Combine marker channels'
          cfg_add_module('pspm.prep.combine_markerchannels');
      end
        end

        % Callback function
        function button_data_preprocessing_Callback(app, event)
      switch event.Value
        case 'Preprocess heart data'
          cfg_add_module('pspm.data_preprocessing.pp_heart_period.pp_heart_data');
        case 'ECG editor'
          %cfg_add_module('pspm.data_preprocessing.pp_heart_period.ecg_editor');
          pspm_ecg_editor();
        case 'Preprocess respiration data'
          cfg_add_module('pspm.data_preprocessing.resp_pp');
        case 'Prepare illuminance GLM'
          cfg_add_module('pspm.data_preprocessing.pp_pupil.process_illuminance');
        case 'Find valid fixations'
          cfg_add_module('pspm.data_preprocessing.pp_pupil.find_valid_fixations');
        case 'Pupil foreshortening error correction'
          cfg_add_module('pspm.data_preprocessing.pp_pupil.pupil_correct');
        case 'Pupil preprocessing'
          cfg_add_module('pspm.data_preprocessing.pp_pupil.pupil_preprocess');
        case 'Pupil size convert'
          cfg_add_module('pspm.data_preprocessing.pp_pupil.pupil_size_convert');
        case 'Gaze convert'
          cfg_add_module('pspm.data_preprocessing.pp_pupil.gaze_convert');
        case 'Find startle sound onsets'
          cfg_add_module('pspm.data_preprocessing.pp_emg.find_sounds');
        case 'Preprocess startle eyeblink EMG'
          cfg_add_module('pspm.data_preprocessing.pp_emg.pp_emg_data');
        case 'Preprocessing SCR'
          cfg_add_module('pspm.data_preprocessing.pp_scr');
      end
        end

        % Button pushed function: button_model_review
        function button_model_review_Callback(app, event)
      pspm_review;
        end

        % Callback function
        function button_report_second_level_Callback(app, event)
      cfg_add_module('pspm.second_level.report');
        end

        % Button pushed function: button_logo
        function PsPMButtonPushed(app, event)
      pspm_show_arms;
        end

        % Callback function
        function button_logoPushed(app, event)
      pspm_show_arms;
        end

        % Button pushed function: button_data_display
        function button_data_displayPushed(app, event)
      pspm_display;
        end

        % Button pushed function: button_data_editor
        function button_data_editorPushed(app, event)
      pspm_data_editor();
        end

        % Button pushed function: button_ecg_editor
        function button_ecg_editorPushed(app, event)
      pspm_ecg_editor();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.layout = uifigure('Visible', 'off');
            colormap(app.layout, 'parula');
            app.layout.Name = 'pspm';
            app.layout.Tag = 'figure1';
            app.GridLayout = uigridlayout(app.layout);
            app.text_attribution = uilabel(app.GridLayout);
            app.text_attribution.Text = {'Version 7.0'; 'Build 12-02-2025 with MATLAB 2024a, The PsPM Team'};
            app.panel_help = uipanel(app.GridLayout);
            app.button_discussion_forum = uibutton(app.panel_help, 'push');
            app.button_discussion_forum.ButtonPushedFcn = createCallbackFcn(app, @PsPM_ButtonDownFcnForum, true);
            app.button_discussion_forum.Tag = 'pushbutton25';
            app.button_discussion_forum.Tooltip = 'Help!';
            app.button_discussion_forum.Text = 'Discussion forum';
            app.button_reference_manual = uibutton(app.panel_help, 'push');
            app.button_reference_manual.ButtonPushedFcn = createCallbackFcn(app, @PsPM_ButtonDownFcnHelp, true);
            app.button_reference_manual.Tag = 'pushbutton24';
            app.button_reference_manual.Tooltip = 'Help!';
            app.button_reference_manual.Text = 'Reference manual';
            app.panel_quit = uipanel(app.GridLayout);
            app.button_quit = uibutton(app.panel_quit, 'push');
            app.button_quit.ButtonPushedFcn = createCallbackFcn(app, @button_quit_Callback, true);
            app.button_quit.Tag = 'QuitGUI';
            app.button_quit.Tooltip = 'Goodbye.';
            app.button_quit.Text = 'Quit';
            app.panel_data_processing = uipanel(app.GridLayout);
            app.button_batch = uibutton(app.panel_data_processing, 'push');
            app.button_batch.ButtonPushedFcn = createCallbackFcn(app, @button_batch_Callback, true);
            app.button_batch.Tag = 'batch_pushbutton';
            app.button_batch.Tooltip = 'Batch Editor';
            app.button_batch.Text = 'Batch';
            app.panel_data_display = uipanel(app.GridLayout);
            app.button_data_display = uibutton(app.panel_data_display, 'push');
            app.button_data_display.ButtonPushedFcn = createCallbackFcn(app, @button_data_displayPushed, true);
            app.button_data_display.Text = 'Data display';
            app.button_model_review = uibutton(app.panel_data_display, 'push');
            app.button_model_review.ButtonPushedFcn = createCallbackFcn(app, @button_model_review_Callback, true);
            app.button_model_review.Tag = 'rev1';
            app.button_model_review.Tooltip = 'Review a first level model';
            app.button_model_review.Text = 'Model review';
            app.button_ecg_editor = uibutton(app.panel_data_display, 'push');
            app.button_ecg_editor.ButtonPushedFcn = createCallbackFcn(app, @button_ecg_editorPushed, true);
            app.button_ecg_editor.Text = 'ECG editor';
            app.button_data_editor = uibutton(app.panel_data_display, 'push');
            app.button_data_editor.ButtonPushedFcn = createCallbackFcn(app, @button_data_editorPushed, true);
            app.button_data_editor.Text = 'Data editor';
            app.button_logo = uibutton(app.GridLayout, 'push');
            app.button_logo.ButtonPushedFcn = createCallbackFcn(app, @PsPMButtonPushed, true);
            app.button_logo.Text = 'PsPM';
            app.title_data_display = uilabel(app.GridLayout);
            app.title_data_display.Text = 'Display and interactive editing';
            app.title_data_processing = uilabel(app.GridLayout);
            app.title_data_processing.Text = 'Data processing';
            app.title_help = uilabel(app.GridLayout);
            app.title_help.Text = 'Help';
            % Show the figure after all components are created
            app.layout.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pspm_appdesigner2019(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.layout)

            % Execute the startup function
            runStartupFcn(app, @(app)PsPM_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.layout)
        end
    end
end